classdef interface < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        ExpandButton            matlab.ui.control.Button
        Label                   matlab.ui.control.Label
        Image                   matlab.ui.control.Image
        FIlePathEditField       matlab.ui.control.EditField
        FIlePathEditFieldLabel  matlab.ui.control.Label
        BrowseButton            matlab.ui.control.Button
    end

    
    properties (Access = private)
        Property % Description
        func=load("detector\Hope.mat");  %detector ที่จะใช้
        pic;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
            app.Label.Text="";
            [file,path] = uigetfile({'*.png';'*.jpg';'*.jpeg';'*.*'},...
                          'Select Image File');
            
            drawnow;
            figure(app.UIFigure);
            
            name = strcat(path,file);
            img = imread(name);
            img = imresize(img,[800,800]);
            [bbox,score] = detect(app.func.detector,img);  %ดู .detector ด้วย
            if isempty(score)
                app.Label.Text = "No Sign Detected";
            else
                % Resize image and bounding boxes to the targetSize.
                sz = size(img,[1 2]);
                scale = [600,800]./sz;
                img = imresize(img,[600,800]);

                % Resize boxes.
                bbox = bboxresize(bbox,scale);
                img = insertObjectAnnotation(img,'rectangle',bbox,score);
            end
            app.FIlePathEditField.Value = name;
            app.Image.Visible = 'on';
            app.Image.ImageSource = img;
            app.ExpandButton.Enable = 'on';
            app.pic=img;
        end

        % Button pushed function: ExpandButton
        function ExpandButtonPushed(app, event)
            imshow(app.pic);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.BrowseButton = uibutton(app.UIFigure, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse';
            app.FIlePathEditFieldLabel = uilabel(app.UIFigure);
            app.FIlePathEditFieldLabel.Text = 'FIle Path';
            app.FIlePathEditField = uieditfield(app.UIFigure, 'text');
            app.Image = uiimage(app.UIFigure);
            app.Image.Visible = 'off';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.ExpandButton = uibutton(app.UIFigure, 'push');
            app.ExpandButton.ButtonPushedFcn = createCallbackFcn(app, @ExpandButtonPushed, true);
            app.ExpandButton.Enable = 'off';
            app.ExpandButton.Text = 'Expand';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = interface

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end